classdef A_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uibuttongroup5  matlab.ui.container.ButtonGroup
        pushbutton1     matlab.ui.control.Button
        pushbutton10    matlab.ui.control.Button
        text8           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        text6           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        text2           matlab.ui.control.Label
        pushbutton15    matlab.ui.control.Button
        uibuttongroup7  matlab.ui.container.ButtonGroup
        pushbutton14    matlab.ui.control.Button
        pushbutton13    matlab.ui.control.Button
        uibuttongroup6  matlab.ui.container.ButtonGroup
        pushbutton12    matlab.ui.control.Button
        pushbutton11    matlab.ui.control.Button
        axes1           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function [mydistance, path_new] = Dijkstra(app, path_raw, map)
                n=length(path_raw);
                sb=1;db=n;%起点终点
                a=zeros(n);%定义n阶零矩阵
                for i=1:n
                    for j=1:n
                        if checkPath(app, path_raw(i,:),path_raw(j,:),map)
                            a(i,j)=distanceCost(app, path_raw(i,:),path_raw(j,:));
                        else
                            a(i,j)=inf;
                        end
                    end
                end
                for i=1:n
                    a(i,i)=0;
                end
                visited(1:n) = 0;
                distance(1:n) = inf; % 保存起点到各顶点的最短距离
                distance(sb) = 0; parent(1:n) = 0;
                for i = 1: n-1
                    temp=distance;
                    id1=find(visited==1); %查找已经标号的点
                    temp(id1)=inf; %已标号点的距离换成无穷
                    [t, u] = min(temp); %找标号值最小的顶点
                    visited(u) = 1; %标记已经标号的顶点
                    id2=find(visited==0); %查找未标号的顶点
                    for v = id2
                        if a(u, v) + distance(u) < distance(v)
                            distance(v) = distance(u) + a(u, v); %修改标号值
                            parent(v) = u;
                        end
                    end
                end
                mypath = [];
                if parent(db) ~= 0 %如果存在路!
                    t = db; mypath = [db];
                    while t ~= sb
                        p = parent(t);
                        mypath = [p mypath];
                        t = p;
                    end
                end
                mydistance = distance(db);
                path_new=zeros(length(mypath),2);
                for i=1:length(mypath)
                    path_new(i,1)=path_raw(mypath(i),1);
                    path_new(i,2)=path_raw(mypath(i),2);
                end
        end
        
        function ReceiveCallback(app, obj, event)
            global s;
            a = fscanf(s);
            disp(a);
            I = 'I Received';
            disp(I);
        end
        
        function feasible = checkPath(app, n, newPos, map)
            feasible=true;
            dir=atan2(newPos(1)-n(1),newPos(2)-n(2));
            for r=0:0.5:sqrt(sum((n-newPos).^2))
                posCheck=n+r.*[sin(dir) cos(dir)];
                if ~(feasiblePoint(app, ceil(posCheck),map) && feasiblePoint(app, floor(posCheck),map) && ...
                        feasiblePoint(app, [ceil(posCheck(1)) floor(posCheck(2))],map) && feasiblePoint(app, [floor(posCheck(1)) ceil(posCheck(2))],map))
                    feasible=false;break;
                end
                if ~feasiblePoint(app, newPos,map), feasible=false; end
            end
        end
        
        function h = distanceCost(app, a, b)
            h = sqrt((a(:,1)-b(:,1)).^2 + (a(:,2)-b(:,2)).^2 );
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function feasible = feasiblePoint(app, point, map)
            feasible=true;
            % check if collission-free spot and inside maps
            if ~(point(1)>=1 && point(1)<=size(map,1) && point(2)>=1 && point(2)<=size(map,2) && map(point(1),point(2))==1)
                feasible=false;
            end
        end
        
        function [RRTree1, pathFound, extendFail] = rrtExtend(app, RRTree1, RRTree2, goal, obs_map, stepsize, maxFailedAttempts, disTh, map, APF_MODE, APF_a, APF_amax, APF_r, APF_po)
            pathFound=[]; %if path found, returns new node connecting the two trees, index of the nodes in the two trees connected
            failedAttempts=0;
            while failedAttempts <= maxFailedAttempts
                if rand < 0.8,
                    sample = rand(1,2) .* size(map); % random sample
                else
                    sample = goal;  % sample taken as goal to bias tree generation to goal 以样本为目标生成偏置树，以目标为目标
                end
            
                [A, I] = min( distanceCost(app, RRTree1(:,1:2),sample) ,[],1); % find the minimum value of each column 求每一列的最小值
                closestNode = RRTree1(I(1),:);
            
                %% moving from qnearest an incremental distance in the direction of qrand
                theta = atan2((sample(2)-closestNode(2)),(sample(1)-closestNode(1)));  % direction to extend sample to produce new node 指示扩展样本以产生新节点
                if APF_MODE == true
                    angle=APF_angle(closestNode,goal,obs_map,length(obs_map));
                    n=length(angle);
            
                    [Fatx,Faty]=APF_attract(closestNode,goal,APF_a,angle(1,:),APF_amax);
                    [Frerxx,Freryy]=APF_repulsion(closestNode,obs_map,APF_r,angle(2:n),n-1,APF_po);
                    Fsumyj=Faty+Freryy;%y方向的合力
                    Fsumxj=Fatx+Frerxx;%x方向的合力
                    angle_apf=atan2(Fsumyj,Fsumxj);%合力与x轴方向的夹角向量
                    newPoint = double(int32(closestNode(1:2) + stepsize * ([cos(theta) sin(theta)]+ [cos(angle_apf) sin(angle_apf)]) ));
            
                else
                    newPoint = double(int32(closestNode(1:2) + stepsize * [cos(theta) sin(theta)]));
                end
                if ~checkPath(app, closestNode(1:2), newPoint, map) % if extension of closest node in tree to the new point is feasible
                    failedAttempts = failedAttempts + 1;
                    continue;
                end
            
                [A, I2] = min( distanceCost(app, RRTree2(:,1:2),newPoint) ,[],1); % find closest in the second tree 在第二棵树中找到最近的
                if distanceCost(app, RRTree2(I2(1),1:2),newPoint) < disTh,        % if both trees are connected 如果两棵树相连
                    pathFound=[newPoint I(1) I2(1)];extendFail=false;break;
                end
                [A, I3] = min( distanceCost(app, RRTree1(:,1:2),newPoint) ,[],1); % check if new node is not already pre-existing in the tree 检查树中是否已经存在新节点
                if distanceCost(app, newPoint,RRTree1(I3(1),1:2)) < disTh, failedAttempts=failedAttempts+1;continue; end
                RRTree1 = [RRTree1;newPoint I(1)];extendFail=false;break; % add node 添加节点
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function A_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to A (see VARARGIN)
            
            % Choose default command line output for A
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            global map;
            global source;
            global goal;
            global map1;
            global path_new
            global str;
            global s;
            global a;
            sorce=[];
            map=[];
        end

        % Button pushed function: pushbutton10
        function pushbutton10_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global map
            % map=rgb2gray(map);
            map=medfilt2(map,[3,3])
            axes(handles.axes1)
            imshow(map);title("灰度化及滤波后")
        end

        % Button pushed function: pushbutton11
        function pushbutton11_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global source
            global map
            axes(handles.axes1)
            imshow(map);
            [y x]=ginput(1);
            x=round(x)
            y=round(y)
            source=[x y];
            set(handles.text2,'String',y)
            set(handles.text3,'String',x)
        end

        % Button pushed function: pushbutton12
        function pushbutton12_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global goal
            global map
            axes(handles.axes1)
            imshow(map);
            [y x]=ginput(1);
            x=round(x)
            y=round(y)
            goal=[x y];
            set(handles.text4,'String',y)
            set(handles.text5,'String',x)
        end

        % Button pushed function: pushbutton13
        function pushbutton13_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global map
            global map1
            global source
            global goal
            global path_new
            global str
            se1=strel('disk',10)
            map1=imerode(map,se1);%膨胀图像
            
            stepsize=20;    % size of each step of the RRT 步长
            disTh=20;       % nodes closer than this threshold are taken as almost the same 比这个阈值更近的节点被认为是几乎相同的
            maxFailedAttempts=1000; %最大迭代次数
            display=true;   % display of RRT
            RRT_connect=0; %双向rrt开关
            APF_MODE=0; %人工势场开关
            DIJ_MODE=1;%Dijkstra优化开关
            APF_a=2; %引力系数
            APF_amax=1 ;%引力最大值
            APF_r=5;%斥力系数
            APF_po=100;%斥力作用距离
            tic;
            
            
            if ~feasiblePoint(app, source,map1), error('source lies on an obstacle or outside map'); end %起点在地图外面结束
            if ~feasiblePoint(app, goal,map1), error('goal lies on an obstacle or outside map'); end %终点在地图外面结束
            if display, axes(handles.axes1),imshow(map);rectangle('position',[1 1 size(map1)-1],'edgecolor','k'); end
            
            RRTree1 = double([source -1]); % First RRT rooted at the source, representation node and parent index 首先，RRT以源、表示节点和父索引为根
            RRTree2 = double([goal -1]);   % Second RRT rooted at the goal, representation node and parent index 第二个RRT以目标、表示节点和父索引为根
            counter=0; %计数器等于0
            tree1ExpansionFail = false; % sets to true if expansion after set number of attempts fails 如果在设置尝试次数后的扩展失败，则设置为true
            tree2ExpansionFail = false; % sets to true if expansion after set number of attempts fails
            
            n=0;
            for i=1:1:length(map1(:,1))
                for j=1:1:length(map1(1,:))
                    if(map1(i,j))==0
                        n=n+1;
                        obs_map(n,1)=i;
                        obs_map(n,2)=j;
                    end
                end
            end
            
            while ~tree1ExpansionFail || ~tree2ExpansionFail  % loop to grow RRTs 循环种植RRTs
                if ~tree1ExpansionFail
                    [RRTree1,pathFound,tree1ExpansionFail] = rrtExtend(app, RRTree1,RRTree2,goal,obs_map,stepsize,maxFailedAttempts,disTh,map1,APF_MODE,APF_a,APF_amax,APF_r,APF_po); % RRT 1 expands from source towards goal  RRT 1从源扩展到目标
                    if ~tree1ExpansionFail && isempty(pathFound) && display
                        line([RRTree1(end,2);RRTree1(RRTree1(end,3),2)],[RRTree1(end,1);RRTree1(RRTree1(end,3),1)],'color','b');
                        counter=counter+1;M(counter)=getframe;
                    end
                end
                if RRT_connect
                    if ~tree2ExpansionFail
                        [RRTree2,pathFound,tree2ExpansionFail] = rrtExtend(app, RRTree2,RRTree1,source,obs_map,stepsize,maxFailedAttempts,disTh,map1,APF_MODE,APF_a,APF_amax,APF_r,APF_po); % RRT 2 expands from goal towards source
                        if ~isempty(pathFound), pathFound(3:4)=pathFound(4:-1:3); end % path found
                        if ~tree2ExpansionFail && isempty(pathFound) && display
                            line([RRTree2(end,2);RRTree2(RRTree2(end,3),2)],[RRTree2(end,1);RRTree2(RRTree2(end,3),1)],'color','r');
                         counter=counter+1;M(counter)=getframe;
                        end
                    end
                end
                if ~isempty(pathFound) % path found
                     if display
                        line([RRTree1(pathFound(1,3),2);pathFound(1,2);RRTree2(pathFound(1,4),2)],[RRTree1(pathFound(1,3),1);pathFound(1,1);RRTree2(pathFound(1,4),1)],'color','green');
                        counter=counter+1;M(counter)=getframe;
                    end
                    path=[pathFound(1,1:2)]; % compute path
                    prev=pathFound(1,3);     % add nodes from RRT 1 first
                    while prev > 0
                        path=[RRTree1(prev,1:2);path];
                        prev=RRTree1(prev,3);
                    end
                    prev=pathFound(1,4); % then add nodes from RRT 2
                    while prev > 0
                        path=[path;RRTree2(prev,1:2)];
                        prev=RRTree2(prev,3);
                    end
                    break;
                end
            end
            
            if size(pathFound,1)<=0, error('no path found. maximum attempts reached'); end %最大迭代次数后没找到终点结束
            pathLength=0; %输出的路径长是0
            for i=1:length(path)-1, pathLength=pathLength+distanceCost(app, path(i,1:2),path(i+1,1:2)); end %输出路径
            fprintf('processing time=%d \nPath Length=%d \n\n', toc,pathLength); %最终路径长度
            axes(handles.axes1)
            imshow(map);
            % rectangle('position',[1 1 size(map1)-1],'edgecolor','k');
            % line(path(:,2),path(:,1));
            if DIJ_MODE
                [path_dis,path_new]=Dijkstra(app, path,map1);
                fprintf('processing time=%d \nPath Length=%d \n', toc,path_dis);
                line(path_new(:,2),path_new(:,1),'color','r');
            end
            
            path_new(:,[1 2])=path_new(:,[2 1]);
            FA=path_new(:);
            FA=FA';
            str=num2str(FA(1));
            for ii = 2:length(FA)
                str = [str,',',num2str(FA(ii))];
            end
            str = [str,',']
            str = [str,'H']
            path_new=num2str(path_new)
            set(handles.text8,'String',path_new);
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global path_new
            global str
            global s
            
            delete(instrfind({'Port'},{'COM6'}));%断开，释放串口对象占用空间
            global s;%建立全局变量s
            s = serial('COM6','BaudRate',9600,'DataBits',8);%设定相关的串口参数
            fopen(s);
            set(s,'BytesAvailableFcnMode','Terminator');
            set(s,'Terminator','H');                    %设立中断，当串口检测到字符H时触发中断
            s.BytesAvailableFcn =@app.ReceiveCallback; %中断触发后处理回调的事
            
            pause(1);
            fprintf(s,path_new);
            pause(0.1);
        end

        % Button pushed function: pushbutton15
        function pushbutton15_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            axes(handles.axes1); %清空坐标轴1
            cla reset;
            set(handles.text4,'string','');
            set(handles.text3,'string','');
            set(handles.text2,'string','');
            set(handles.text5,'string','');
            set(handles.text8,'string','');
            clear;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % vid = videoinput('winvideo', 2, 'YUY2_640x480');
            % set(vid,'ReturnedColorSpace','rgb');
            % frame = getsnapshot(vid);
            % imshow(frame);
            global map
            map=im2bw(imread('map7.bmp'))
            axes(handles.axes1)
            imshow(map);title('拍照')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'A';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uibuttongroup6 = uibuttongroup(app.figure1);
            app.uibuttongroup6.Title = 'Step2';
            app.uibuttongroup6.Tag = 'uibuttongroup6';
            app.pushbutton11 = uibutton(app.uibuttongroup6, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11_Callback, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = '设置起点';
            app.pushbutton12 = uibutton(app.uibuttongroup6, 'push');
            app.pushbutton12.ButtonPushedFcn = createCallbackFcn(app, @pushbutton12_Callback, true);
            app.pushbutton12.Tag = 'pushbutton12';
            app.pushbutton12.Text = '设置终点';
            app.uibuttongroup7 = uibuttongroup(app.figure1);
            app.uibuttongroup7.Title = 'Step3';
            app.uibuttongroup7.Tag = 'uibuttongroup7';
            app.pushbutton13 = uibutton(app.uibuttongroup7, 'push');
            app.pushbutton13.ButtonPushedFcn = createCallbackFcn(app, @pushbutton13_Callback, true);
            app.pushbutton13.Tag = 'pushbutton13';
            app.pushbutton13.Text = '搜寻路径';
            app.pushbutton14 = uibutton(app.uibuttongroup7, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Text = '发送坐标';
            app.pushbutton15 = uibutton(app.figure1, 'push');
            app.pushbutton15.ButtonPushedFcn = createCallbackFcn(app, @pushbutton15_Callback, true);
            app.pushbutton15.Tag = 'pushbutton15';
            app.pushbutton15.Text = '重置';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'X';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Y';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'X';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Y';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = '起点';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = '终点';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = '路径';
            app.uibuttongroup5 = uibuttongroup(app.figure1);
            app.uibuttongroup5.Title = 'Step1';
            app.uibuttongroup5.Tag = 'uibuttongroup5';
            app.pushbutton10 = uibutton(app.uibuttongroup5, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10_Callback, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = '图像预处理';
            app.pushbutton1 = uibutton(app.uibuttongroup5, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = '拍照';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)A_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end